classdef AnalysePlotForceDirection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FrameRateEditField        matlab.ui.control.NumericEditField
        FrameRateEditFieldLabel   matlab.ui.control.Label
        Frame1Box                 matlab.ui.control.Spinner
        Frame2Box                 matlab.ui.control.Spinner
        SymbolSizeEditField       matlab.ui.control.NumericEditField
        SymbolSizeEditFieldLabel  matlab.ui.control.Label
        SequenceEditField         matlab.ui.control.EditField
        SequenceEditFieldLabel    matlab.ui.control.Label
        ArrowSizeEditField        matlab.ui.control.NumericEditField
        ArrowSizeEditFieldLabel   matlab.ui.control.Label
        FramesLabel               matlab.ui.control.Label
        SaveImageButton           matlab.ui.control.Button
        exitButton                matlab.ui.control.Button
        Slider1                   matlab.ui.control.Slider
        Slider2                   matlab.ui.control.Slider
        UIAxesTripod2             matlab.ui.control.UIAxes
        UIAxesTripod1             matlab.ui.control.UIAxes
        UIAxesStep6               matlab.ui.control.UIAxes
        UIAxesStep5               matlab.ui.control.UIAxes
        UIAxesStep4               matlab.ui.control.UIAxes
        UIAxesStep3               matlab.ui.control.UIAxes
        UIAxesStep2               matlab.ui.control.UIAxes
        UIAxesStep1               matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        FileName;
        FrameName;
        
        ListFrame;
        Nframe;% Description
        XYtail; % Description
        XYleg;
        XYhead;
        Fxy
        Angle;
        Step;


        AxesStep;
        Scatter;
        PlotBody;
        PlotForce
        Quiver;
        Rectangle;
    end
    
    methods (Access = public)
        
        function DataStart(app,FileName,Sequence)
            app.FileName=FileName;
            app.FrameName=Sequence;


            % Show data
            app.SequenceEditField.Value=Sequence;

            % Read Image Name
            app.StartReadData();
            app.UpdateSlider();
            app.PlotAll();
            app.UpdateGraph();
        end
        
    end
    
    methods (Access = private)
        
        function StartReadData(app)
            load(fullfile(app.FileName,'step2',['ant_' app.FrameName '.mat']),'AntArray');
            load(fullfile(app.FileName,'step2',['TotalMvt_' app.FrameName '.mat']),'TotalLegMvt');
            app.Nframe=size(AntArray,1);
            

            XYh=reshape(AntArray(:,1,:),[app.Nframe,2]);
            XYt=reshape(AntArray(:,2,:),[app.Nframe,2]);
            XYc=(XYt+XYh)/2;
            XYh=XYh-XYc;
            XYt=XYt-XYc;

            dXY=XYh-XYt;
            Angle=-angle(dXY(:,1)+1i*dXY(:,2));

            XYl=cell(1,6);
            Fxy=cell(1,6);
            for i=1:6
                XYl{i}=reshape(AntArray(:,i+2,:),[app.Nframe,2])-XYc;
                Fxy{i}=TotalLegMvt(i).Disp(:,[1 2]);
            end
            
            app.XYhead=XYh;
            app.XYtail=XYt;
            app.XYleg=XYl;
            app.Fxy=Fxy;
            app.Angle=Angle;

            app.ReadStep();
        end
        
        function ReadStep(app)
            FileName2=fullfile(app.FileName,'step3',[ 'step_' app.FrameName '.txt']);

            if(exist(FileName2,'file'))
                
                Fid=fopen(FileName2,'r');
                Step=cell(6,1);
                for ii=1:6
                    ch=0;
                    while(~ch & ~feof(Fid))
                        tline = fgetl(Fid);
                        ch = startsWith(tline,'leg','IgnoreCase',true);
                    end
                    if(~feof(Fid))
                        Leg=sscanf(tline,'%s %d');
                        Leg=Leg(end);
                        if(Leg>0 && Leg<7)
                            A=fscanf(Fid,'%d %d\n');
                            Step{Leg}=zeros(length(A)/2,2);
                            Step{Leg}(:,1)=A(1:2:end);
                            Step{Leg}(:,2)=A(2:2:end);
                        end

                    end
                end
                fclose(Fid);

            else
                Step=cell(6,1);
                for i=1:6
                    Step{i}=[];
                end
            end

            app.Step=Step;
        end

        function UpdateSlider(app)

            app.Slider2.Limits=[1 app.Nframe];
            app.Slider1.Limits=[1 app.Nframe];

            app.Slider1.Value=1;
            app.Slider2.Value=app.Nframe;

            app.Frame1Box.Value=app.Slider1.Value;
            app.Frame2Box.Value=app.Slider2.Value;

            app.Slider1.MajorTicks=[1 app.Nframe];
            app.Slider2.MajorTicks=[1 app.Nframe];

            app.Slider1.MinorTicks=1:2:app.Nframe;
            app.Slider2.MinorTicks=1:2:app.Nframe;
            
        end
        

        function UpdateGraph(app)       
            app.ListFrame=app.Slider1.Value:app.Slider2.Value;
            ListFrame=app.ListFrame;
            Time=(ListFrame-ListFrame(1))/60;

            
            [XYh,XYt,XYl,Fxy]=app.RotatePosition();
            Tripod(1,:)=[1 5 3];
            Tripod(2,:)=[4 2 6];

            Scatter=app.Scatter;
            Quiver=app.Quiver;
            PlotBody=app.PlotBody;
            
            for i=1:2
                for j=1:3
                    Scatter(i,j).XData=XYl{Tripod(i,j)}(:,1);
                    Scatter(i,j).YData=XYl{Tripod(i,j)}(:,2);
                    Scatter(i,j).SizeData=app.SymbolSizeEditField.Value;
                    Scatter(i,j).CData=Time;

                    Quiver(i,j).XData=XYl{Tripod(i,j)}(:,1);
                    Quiver(i,j).YData=XYl{Tripod(i,j)}(:,2);
                    Quiver(i,j).VData=-Fxy{Tripod(i,j)}(:,1)*app.ArrowSizeEditField.Value;
                    Quiver(i,j).UData=-Fxy{Tripod(i,j)}(:,2)*app.ArrowSizeEditField.Value;

                end

                PlotBody(i,1).XData=XYt(1,1);
                PlotBody(i,1).YData=XYt(1,2);
                PlotBody(i,1).UData=XYh(1,1)-XYt(1,1);
                PlotBody(i,1).VData=XYh(1,2)-XYt(1,2);


                PlotBody(i,2).XData=XYt(end,1);
                PlotBody(i,2).YData=XYt(end,2);
                PlotBody(i,2).UData=XYh(end,1)-XYt(end,1);
                PlotBody(i,2).VData=XYh(end,2)-XYt(end,2);
            end

            Lim1=app.UIAxesTripod1.XLim;
            Lim2=app.UIAxesTripod2.XLim;

            Lim=[min([Lim1(1) Lim2(1)]) max([Lim1(end) Lim2(end)]) ];
            app.UIAxesTripod1.XLim=Lim;
            app.UIAxesTripod2.XLim=Lim;

            Lim1=app.UIAxesTripod1.YLim;
            Lim2=app.UIAxesTripod2.YLim;
            Lim=[min([Lim1(1) Lim2(1)]) max([Lim1(end) Lim2(end)]) ];
            app.UIAxesTripod1.YLim=Lim;
            app.UIAxesTripod2.YLim=Lim;
            

            app.UpdateGraphStep();
        end
        
        function UpdateGraphStep(app)
            TimeList=(0:(app.Nframe-1))/app.FrameRateEditField.Value;
            for i=1:6
                F=sqrt( app.Fxy{i}(:,1).^2 + app.Fxy{i}(:,2).^2);
                app.PlotForce(i).XData=TimeList;
                app.PlotForce(i).YData=F;
                app.AxesStep(i).XLim=[TimeList(1) TimeList(end)];
            end
            
            app.PlotRectangle;
        end

        function PlotRectangle(app)
            Rectangle=app.Rectangle;
            Step=app.Step;

            for i=1:length(Rectangle)
                delete(Rectangle(i));
            end

            Time=(0:app.Nframe)/app.FrameRateEditField.Value;
            Rectangle=gobjects(0);

            for i=1:6
                Nstep=size(Step{i},1);
                % axes(AxesBox1(i));
                YLim=app.AxesStep(i).YLim;
                for j=1:(Nstep)
                    Lt=length(Time);
                    S1=min(max(1,Step{i}(j,1)),Lt);
                    S2=min(max(1,Step{i}(j,2)),Lt);
                    if(S1>1)
                        t1=(Time(S1-1)+Time(S1))/2;
                    else
                        t1=Time(S1);
                    end

                    if(S2<Lt)
                        t2=(Time(S2)+Time(S2+1))/2;
                    else
                        t2=Time(S2);
                    end
                    dt=t2-t1;

                    FaceColor= [1 0 0 0.3];
                    
                    R=rectangle('Position',[t1 YLim(1) dt YLim(2)-YLim(1) ],'EdgeColor','none','FaceColor', FaceColor,'Parent',app.AxesStep(i));
                    Rectangle(end+1)=R;
                end
            end


            for i=1:6
                YLim=app.AxesStep(i).YLim;

                S1=app.Frame1Box.Value;
                S2=app.Frame2Box.Value;
                t1=Time(S1);
                t2=Time(S2);
                dt=t2-t1;

                FaceColor= [0.5 0.5 0.5 0.3];

                R=rectangle('Position',[t1 YLim(1) dt YLim(2)-YLim(1) ],'EdgeColor','r','FaceColor', FaceColor,'Parent',app.AxesStep(i));
                Rectangle(end+1)=R;
            end
            app.Rectangle=Rectangle;

        end


        function PlotAll(app)

            hold(app.UIAxesTripod1,'on')
            hold(app.UIAxesTripod2,'on')


            Size=10;
            Symb='s>o';
            for i=1:3
                Scatter(1,i)=scatter(1,1,Size*4,1,Symb(i),'filled','Parent',app.UIAxesTripod1);
                Scatter(2,i)=scatter(1,1,Size*4,1,Symb(i),'filled','Parent',app.UIAxesTripod2);

                Quiver(1,i)=quiver(1,1,1,1,'off','k','Parent',app.UIAxesTripod1);
                Quiver(2,i)=quiver(1,1,1,1,'off','k','Parent',app.UIAxesTripod2);
            end

            PlotBody(1,1)=quiver(0,0,1,1,'off', 'b-s','LineWidth',1,'MaxHeadSize',0.2,'ShowArrowHead','on','parent',app.UIAxesTripod1);
            PlotBody(1,2)=quiver(0,0,1,1,'off', 'g-s','Color',[0 0.5 0],'LineWidth',1,'MaxHeadSize',0.2,'ShowArrowHead','on','parent',app.UIAxesTripod1);

            PlotBody(2,1)=quiver(0,0,1,1,'off', 'b-s','LineWidth',1,'MaxHeadSize',0.2,'ShowArrowHead','on','parent',app.UIAxesTripod2);
            PlotBody(2,2)=quiver(0,0,1,1,'off', 'g-s','Color',[0 0.5 0],'LineWidth',1,'MaxHeadSize',0.2,'ShowArrowHead','on','parent',app.UIAxesTripod2);

            hold(app.UIAxesTripod1,'off')
            hold(app.UIAxesTripod2,'off')
            app.UIAxesTripod1.YDir="reverse";
            app.UIAxesTripod2.YDir="reverse";
            colorbar(app.UIAxesTripod1);
            colorbar(app.UIAxesTripod2);
            daspect(app.UIAxesTripod1,[1 1 1])
            daspect(app.UIAxesTripod2,[1 1 1])
            
            app.Scatter=Scatter;
            app.Quiver=Quiver;
            app.PlotBody=PlotBody;


            app.AxesStep=gobjects(6,1);
            app.AxesStep(1)=app.UIAxesStep1;
            app.AxesStep(5)=app.UIAxesStep2;
            app.AxesStep(3)=app.UIAxesStep3;
            app.AxesStep(4)=app.UIAxesStep4;
            app.AxesStep(2)=app.UIAxesStep5;
            app.AxesStep(6)=app.UIAxesStep6;
            YLABEL={'R1' 'R2' 'R3' 'L1' 'L2' 'L3'};
            for i=1:6
                 ylabel(app.AxesStep(i),YLABEL{i})
            end
            xlabel(app.AxesStep(6),'Time (s)')

            app.PlotForce=gobjects(6,1);
            for i=1:6
                app.PlotForce(i)=plot(0,0,'b','Parent',app.AxesStep(i));
            end


        end
        
        
        function [XYh,XYt,XYl,Fxy]= RotatePosition(app)
            ListFrame=app.ListFrame;
            Angle=mean(app.Angle(ListFrame));

            XYt=app.XYtail(ListFrame,:);
            XYh=app.XYhead(ListFrame,:);

            XYl=app.XYleg;
            Fxy=app.Fxy;
            for i=1:6
                XYl{i}=XYl{i}(ListFrame,:);
                Fxy{i}=Fxy{i}(ListFrame,:);
            end

            R=[cos(Angle) -sin(Angle); sin(Angle) cos(Angle)];
            for j=1:length(ListFrame)
                for i=1:6
                    XYl{i}(j,:)=R*[XYl{i}(j,1); XYl{i}(j,2)];
                    Fxy{i}(j,:)=R*[Fxy{i}(j,1); Fxy{i}(j,2)];
                end
                XYh(j,:)=R*[XYh(j,1); XYh(j,2)];
                XYt(j,:)=R*[XYt(j,1); XYt(j,2)];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Slider1
        function Slider1ValueChanged(app, event)
            Value=min(round(app.Slider1.Value),app.Nframe-1);
            app.Slider1.Value=Value;
            app.Slider2.Value=max(app.Slider2.Value,Value+1);

            app.Frame1Box.Value=app.Slider1.Value;
            app.Frame2Box.Value=app.Slider2.Value;

            app.UpdateGraph();
        end

        % Value changed function: Slider2
        function Slider2ValueChanged(app, event)
            Value=max(round(app.Slider2.Value),2);
            app.Slider2.Value=Value;
            app.Slider1.Value=min(app.Slider1.Value,Value-1);

            app.Frame1Box.Value=app.Slider1.Value;
            app.Frame2Box.Value=app.Slider2.Value;
            app.UpdateGraph();
        end

        % Value changed function: ArrowSizeEditField
        function ArrowSizeEditFieldValueChanged(app, event)
            app.UpdateGraph();
        end

        % Value changed function: SymbolSizeEditField
        function SymbolSizeEditFieldValueChanged(app, event)
             app.UpdateGraph();
        end

        % Value changed function: Frame1Box
        function Frame1BoxValueChanged(app, event)
            app.Slider1.Value  = app.Frame1Box.Value;
            app.Slider1ValueChanged;
        end

        % Value changed function: Frame2Box
        function Frame2BoxValueChanged(app, event)
            app.Slider2.Value  = app.Frame2Box.Value;
            app.Slider2ValueChanged;
        end

        % Button pushed function: SaveImageButton
        function SaveImageButtonPushed(app, event)
            f=figure('position',[20 50 400 800]);

            ax1=copyobj(app.UIAxesTripod1,f);
            Pos=[25 400 350 350];
            ax1.Position=Pos;
            ax1.Units='normalized';
            Pos=[25 10 350 350];
            ax2=copyobj(app.UIAxesTripod2,f);
            ax2.Position=Pos;
            ax2.Units='normalized';
            colorbar(ax1);
            colorbar(ax2);
            StepName=sprintf('%d_%d',app.Slider1.Value,app.Slider2.Value);
            fname=fullfile(app.FileName,'step2',['Force_step_' app.FrameName '_step_' StepName '.mat']);
            vm_save_image(fname,[1 2]*5,f,0);
            close(f);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesStep1 = uiaxes(app.UIFigure);
            app.UIAxesStep2 = uiaxes(app.UIFigure);
            app.UIAxesStep3 = uiaxes(app.UIFigure);
            app.UIAxesStep4 = uiaxes(app.UIFigure);
            app.UIAxesStep5 = uiaxes(app.UIFigure);
            app.UIAxesStep6 = uiaxes(app.UIFigure);
            xlabel(app.UIAxesStep6, 'Frame')
            app.UIAxesTripod1 = uiaxes(app.UIFigure);
            app.UIAxesTripod2 = uiaxes(app.UIFigure);
            app.Slider2 = uislider(app.UIFigure);
            app.Slider2.ValueChangedFcn = createCallbackFcn(app, @Slider2ValueChanged, true);
            app.Slider1 = uislider(app.UIFigure);
            app.Slider1.ValueChangedFcn = createCallbackFcn(app, @Slider1ValueChanged, true);
            app.Slider1.Value = 100;
            app.exitButton = uibutton(app.UIFigure, 'push');
            app.exitButton.Text = 'exit';
            app.SaveImageButton = uibutton(app.UIFigure, 'push');
            app.SaveImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImageButton.Text = 'Save Image';
            app.FramesLabel = uilabel(app.UIFigure);
            app.FramesLabel.Text = 'Frames';
            app.ArrowSizeEditFieldLabel = uilabel(app.UIFigure);
            app.ArrowSizeEditFieldLabel.Text = 'ArrowSize';
            app.ArrowSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.ArrowSizeEditField.Limits = [0 Inf];
            app.ArrowSizeEditField.ValueChangedFcn = createCallbackFcn(app, @ArrowSizeEditFieldValueChanged, true);
            app.ArrowSizeEditField.Value = 20;
            app.SequenceEditFieldLabel = uilabel(app.UIFigure);
            app.SequenceEditFieldLabel.Text = 'Sequence';
            app.SequenceEditField = uieditfield(app.UIFigure, 'text');
            app.SequenceEditField.Value = 'Seq';
            app.SymbolSizeEditFieldLabel = uilabel(app.UIFigure);
            app.SymbolSizeEditFieldLabel.Text = 'SymbolSize';
            app.SymbolSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.SymbolSizeEditField.Limits = [1 Inf];
            app.SymbolSizeEditField.ValueChangedFcn = createCallbackFcn(app, @SymbolSizeEditFieldValueChanged, true);
            app.SymbolSizeEditField.Value = 30;
            app.Frame2Box = uispinner(app.UIFigure);
            app.Frame2Box.Limits = [0 Inf];
            app.Frame2Box.ValueChangedFcn = createCallbackFcn(app, @Frame2BoxValueChanged, true);
            app.Frame2Box.Value = 100;
            app.Frame1Box = uispinner(app.UIFigure);
            app.Frame1Box.Limits = [0 Inf];
            app.Frame1Box.ValueChangedFcn = createCallbackFcn(app, @Frame1BoxValueChanged, true);
            app.FrameRateEditFieldLabel = uilabel(app.UIFigure);
            app.FrameRateEditFieldLabel.Text = 'FrameRate';
            app.FrameRateEditField = uieditfield(app.UIFigure, 'numeric');
            app.FrameRateEditField.Limits = [0 Inf];
            app.FrameRateEditField.Value = 60;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AnalysePlotForceDirection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end