classdef CreateCheckSynchro < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FrameBox                matlab.ui.control.NumericEditField
        FrameEditFieldLabel     matlab.ui.control.Label
        SequenceEditField       matlab.ui.control.EditField
        SequenceEditFieldLabel  matlab.ui.control.Label
        FramePlusBtn            matlab.ui.control.Button
        FrameMinusBtn           matlab.ui.control.Button
        DelayMinusBtn           matlab.ui.control.Button
        DelayPlusBtn            matlab.ui.control.Button
        DelayBox                matlab.ui.control.NumericEditField
        DelayEditFieldLabel     matlab.ui.control.Label
        FrameSlider             matlab.ui.control.Slider
        FrameSliderLabel        matlab.ui.control.Label
        ExitButton              matlab.ui.control.StateButton
        ValidateDelayButton     matlab.ui.control.StateButton
        UIAxesSmall2            matlab.ui.control.UIAxes
        UIAxesSmall1            matlab.ui.control.UIAxes
        UIAxesLarge             matlab.ui.control.UIAxes
        ContextMenu             matlab.ui.container.ContextMenu
        Menu                    matlab.ui.container.Menu
        Menu2                   matlab.ui.container.Menu
    end

    
    properties (Access = private)
        FileName='';
        FrameName='';
        NameImage1='';
        NameImage2='';
        Nini=0;
        Nimage=0;
        Im1=[];
        Im2=[];
        ImRef2=[];
        IntMin1=0;
        IntMax1=0;
        IntMin2=0;
        IntMax2=0;

        PlotIm;
        PlotData;

        Xc=0;
        Yc=0;

    end
    
    properties (Access = public)
    end
    
    methods (Access = private)
                
        function ClickZoom(app,Axes)
            % switch Axes
                % case 1
                    XX=Axes.CurrentPoint;
        % case 2
                    % XX=app.UIAxesSmall1.CurrentPoint;
            % end
            
            app.Yc=XX(2,2);
            app.Xc=XX(1,1);
            app.UpdateImage;
        end

        function Delay(app,Step)
            app.DelayBox.Value=app.DelayBox.Value+Step;
            app.DelayBoxValueChanged;
        end
        
        function StepFrame(app,Step)
            app.FrameSlider.Value=max(min(app.FrameSlider.Value+Step, app.FrameSlider.Limits(2)),app.FrameSlider.Limits(1));
            app.FrameSliderValueChanged;
        end

        function PlotAll(app)

            function Click(Src,~)
            app.ClickZoom(Src.Parent)
            end
   
            PLOTIM(1)=imshow([0],[0 1],'Parent',app.UIAxesLarge);
            PLOTIM(1).ButtonDownFcn=@Click;

            PLOTIM(2)=imshow([0],[0 1],'Parent',app.UIAxesSmall1);
            PLOTIM(2).ButtonDownFcn=@Click;

            PLOTIM(3)=imshow([0],[0 1],'Parent',app.UIAxesSmall2);
            PLOTIM(3).ButtonDownFcn=@Click;

            app.PlotIm=PLOTIM;
            hold(app.UIAxesLarge,'on');
            app.PlotData=plot(0, 0 ,'yo','Parent',app.UIAxesLarge);
            hold(app.UIAxesLarge,'off');


        end

        function UpdateImage(app)    
            Frame=app.FrameSlider.Value;
            app.Im1=double(imread(sprintf(app.NameImage1,Frame-1+app.Nini)));
            app.Im2=double(imread(sprintf(app.NameImage2,Frame-1+app.Nini+app.DelayBox.Value)));
            app.Im2=app.Im2-app.ImRef2;

            app.PlotIm(1).CData=(app.Im1-app.IntMin1)/(app.IntMax1-app.IntMin1);
            app.PlotIm(2).CData=(app.Im1-app.IntMin1)/(app.IntMax1-app.IntMin1);
            app.PlotIm(3).CData=(app.Im2-app.IntMin2)/(app.IntMax2-app.IntMin2);

            app.UIAxesSmall1.XLim=[-100 100]+app.Xc;
            app.UIAxesSmall1.YLim=[-100 100]+app.Yc;

            app.UIAxesSmall2.XLim=[-100 100]+app.Xc;
            app.UIAxesSmall2.YLim=[-100 100]+app.Yc;

            app.PlotData.XData=app.Xc;
            app.PlotData.YData=app.Yc;
        end

        function StartReadImage(app)
            [app.NameImage1,app.Nini,app.Nimage]= vm_ListAllImage(fullfile(app.FileName,'step1',app.FrameName,'Ant','Ant*.png'));
            [app.NameImage2,app.Nini,app.Nimage]= vm_ListAllImage(fullfile(app.FileName,'step1',app.FrameName,'Pillar','Pillar*.png'));

            app.Im1=double(imread(sprintf(app.NameImage1,app.Nini)));
            app.IntMin1=min(app.Im1(:));
            app.IntMax1=max(app.Im1(:));

            app.ImRef2=double(imread(fullfile(app.FileName,'step1',app.FrameName,'Pillar_ref.png')));
            app.IntMin2=-12; app.IntMax2=12;

            app.Xc=size(app.Im1,2)/2;
            app.Yc=size(app.Im1,1)/2;
        end
    
        function UpdateSlider(app)
            Delay=app.DelayBox.Value;
            if(Delay>=0)
            Max=app.Nimage-Delay;
            Min=1;
            else
                Max=app.Nimage;
                Min=1-Delay;
            end

            
            app.FrameSlider.Limits=[Min Max];
            List=round(Min:(Max-Min)/5:Max);
            app.FrameSlider.MajorTicks= List;
            app.FrameSlider.MinorTicks= Min:Max;

        end
    end
    
    methods (Access = public)
        function DataStart(app,FileName,Sequence)
            app.FileName=FileName;
            app.FrameName=Sequence;
            

            % Show data
            app.SequenceEditField.Value=Sequence;
            
            % Read Image Name
            app.StartReadImage;
            app.PlotAll;
            app.UpdateSlider;
            app.FrameSlider.Value=1;
            app.UpdateImage;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DelayPlusBtn
        function DelayPlusBtnValueChanged(app, event)
            app.Delay(+1);
        end

        % Button pushed function: DelayMinusBtn
        function DelayMinuBtnValueChanged(app, event)
            app.Delay(-1);
        end

        % Value changed function: DelayBox
        function DelayBoxValueChanged(app, event)
            app.UpdateSlider;
            app.UpdateImage;
        end

        % Value changed function: FrameSlider
        function FrameSliderValueChanged(app, event)
            Value = round(app.FrameSlider.Value);
            app.FrameSlider.Value=Value;
            app.FrameBox.Value=Value;
            app.UpdateImage;
        end

        % Button pushed function: FramePlusBtn
        function FramePlusBtnButtonPushed(app, event)
            app.StepFrame(+1);
        end

        % Button pushed function: FrameMinusBtn
        function FrameMinusBtnButtonPushed(app, event)
            app.StepFrame(-1)
        end

        % Value changed function: ValidateDelayButton
        function ValidateDelayButtonValueChanged(app, event)
            Delay=app.DelayBox.Value
            if(Delay==0)
                app.delete;
            else
                selection = uiconfirm(app.UIFigure,"This step will delete few images for synchronyzation.        Are you sure?","Are you sure?",'Option',{'Yes', 'No'},'DefaultOption',2 );
                switch selection
                    case "No"
                    case "Yes"
                         MessageBox = uiprogressdlg(app.UIFigure,'Title','Please Wait','Message','Moving Files');
                         MessageBox.Value = 0.;
                       
                        
                        [~]=mkdir(fullfile(app.FileName,'step1',['temp_' app.FrameName],'Ant'));
                        [~]=mkdir(fullfile(app.FileName,'step1',['temp_' app.FrameName],'Pillar'));
                        copyfile(fullfile(app.FileName,'step1', app.FrameName,'Pillar_ref.png'),fullfile(app.FileName,'step1',['temp_' app.FrameName],'Pillar_ref.png'));
                        copyfile(fullfile(app.FileName,'step1', app.FrameName,'Ant_ref.png'),fullfile(app.FileName,'step1',['temp_' app.FrameName],'Ant_ref.png'));


                        for i=1:app.Nimage
                            MessageBox.Value = i/(app.Nimage);

                            FName1=sprintf(app.NameImage1,i-1);
                            FName2=fullfile(app.FileName,'step1',['temp_' app.FrameName],'Ant',sprintf('Ant_%0.4d.png',i-1));
                            copyfile(FName1,FName2)

                            Ind=max(i+Delay,1);
                            Ind=min(Ind,app.Nimage);
                            FName1=sprintf(app.NameImage2,Ind-1);
                            FName2=fullfile(app.FileName,'step1',['temp_' app.FrameName],'Pillar',sprintf('Pillar_%0.4d.png',i-1));
                            copyfile(FName1,FName2)

                        end
                        movefile(fullfile(app.FileName,'step1',['' app.FrameName]),fullfile(app.FileName,'step1',['old_' app.FrameName]))
                        movefile(fullfile(app.FileName,'step1',['temp_' app.FrameName]), fullfile(app.FileName,'step1',['' app.FrameName]));
                        app.delete;

                end
            end  
        end

        % Value changed function: ExitButton
        function ExitButtonValueChanged(app, event)
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesLarge = uiaxes(app.UIFigure);
            app.UIAxesLarge.XTick = [];
            app.UIAxesLarge.YTick = [];
            app.UIAxesSmall1 = uiaxes(app.UIFigure);
            app.UIAxesSmall1.XTick = [];
            app.UIAxesSmall1.YTick = [];
            app.UIAxesSmall2 = uiaxes(app.UIFigure);
            app.UIAxesSmall2.XTick = [];
            app.UIAxesSmall2.YTick = [];
            app.ValidateDelayButton = uibutton(app.UIFigure, 'state');
            app.ValidateDelayButton.ValueChangedFcn = createCallbackFcn(app, @ValidateDelayButtonValueChanged, true);
            app.ValidateDelayButton.Text = 'Validate Delay';
            app.ExitButton = uibutton(app.UIFigure, 'state');
            app.ExitButton.ValueChangedFcn = createCallbackFcn(app, @ExitButtonValueChanged, true);
            app.ExitButton.Text = 'Exit';
            app.FrameSliderLabel = uilabel(app.UIFigure);
            app.FrameSliderLabel.Text = 'Frame';
            app.FrameSlider = uislider(app.UIFigure);
            app.FrameSlider.Limits = [0 10];
            app.FrameSlider.MajorTicks = [0 5 10];
            app.FrameSlider.ValueChangedFcn = createCallbackFcn(app, @FrameSliderValueChanged, true);
            app.FrameSlider.MinorTicks = [0 1 2 3 4 5 6 7 8 9 10];
            app.FrameSlider.Value = 1.1;
            app.DelayEditFieldLabel = uilabel(app.UIFigure);
            app.DelayEditFieldLabel.Text = 'Delay';
            app.DelayBox = uieditfield(app.UIFigure, 'numeric');
            app.DelayBox.RoundFractionalValues = 'on';
            app.DelayBox.ValueChangedFcn = createCallbackFcn(app, @DelayBoxValueChanged, true);
            app.DelayPlusBtn = uibutton(app.UIFigure, 'push');
            app.DelayPlusBtn.ButtonPushedFcn = createCallbackFcn(app, @DelayPlusBtnValueChanged, true);
            app.DelayPlusBtn.Text = '+';
            app.DelayMinusBtn = uibutton(app.UIFigure, 'push');
            app.DelayMinusBtn.ButtonPushedFcn = createCallbackFcn(app, @DelayMinuBtnValueChanged, true);
            app.DelayMinusBtn.Text = '-';
            app.FrameMinusBtn = uibutton(app.UIFigure, 'push');
            app.FrameMinusBtn.ButtonPushedFcn = createCallbackFcn(app, @FrameMinusBtnButtonPushed, true);
            app.FrameMinusBtn.Text = '<';
            app.FramePlusBtn = uibutton(app.UIFigure, 'push');
            app.FramePlusBtn.ButtonPushedFcn = createCallbackFcn(app, @FramePlusBtnButtonPushed, true);
            app.FramePlusBtn.Text = '>';
            app.SequenceEditFieldLabel = uilabel(app.UIFigure);
            app.SequenceEditFieldLabel.Text = 'Sequence';
            app.SequenceEditField = uieditfield(app.UIFigure, 'text');
            app.FrameEditFieldLabel = uilabel(app.UIFigure);
            app.FrameEditFieldLabel.Text = 'Frame';
            app.FrameBox = uieditfield(app.UIFigure, 'numeric');
            app.FrameBox.Limits = [0 Inf];
            app.FrameBox.RoundFractionalValues = 'on';
            app.FrameBox.Editable = 'off';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CreateCheckSynchro

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end