classdef InterfaceForceAnalysisApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SaveResultintextFileButton  matlab.ui.control.Button
        ZoomForceButton             matlab.ui.control.Button
        PlotForceDirectionButton    matlab.ui.control.Button
        CalculateStepButton         matlab.ui.control.Button
        TrajectoryButton            matlab.ui.control.Button
        AverageForceButton          matlab.ui.control.Button
        ForceAnalysisButton         matlab.ui.control.Button
        FrameEditField              matlab.ui.control.EditField
        EditField_2Label            matlab.ui.control.Label
        FrameListBox                matlab.ui.control.ListBox
        Label                       matlab.ui.control.Label
        FileNameEditField           matlab.ui.control.EditField
        FileNameEditFieldLabel      matlab.ui.control.Label
    end

    
    properties (Access = private)
        FileName % Description
    end
    
    methods (Access = private)
        
    end
    
    methods (Access = public)

        
        function DataStart(app,FileName)
            app.FileName=FileName;
            app.FileNameEditField.Value=FileName;
            DIR=dir(fullfile(FileName,'step1','Frame_*-*'));
            DIR=DIR([DIR.isdir]);
            Items=cell(1,length(DIR));
            for i=1:length(DIR)
                Items{i}=DIR(i).name;
            end
            app.FrameListBox.Items=Items;
            app.FrameEditField.Value=Items{1};
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FrameListBox
        function FrameListBoxValueChanged(app, event)
            value = app.FrameListBox.Value;
            app.FrameEditField.Value=value ;
        end

        % Button pushed function: ForceAnalysisButton
        function ForceAnalysisButtonPushed(app, event)
            FrameName=app.FrameEditField.Value;
            AnalyseForce(app.FileName,FrameName);
        end

        % Button pushed function: AverageForceButton
        function AverageForceButtonPushed(app, event)
            FrameName=app.FrameEditField.Value;
            AnalyseAverage(app.FileName,FrameName);
        end

        % Button pushed function: TrajectoryButton
        function TrajectoryButtonPushed(app, event)
            FrameName=app.FrameEditField.Value;
            AnalyseTrajectory(app.FileName,FrameName);
        end

        % Button pushed function: CalculateStepButton
        function CalculateStepButtonPushed(app, event)
            FrameName=app.FrameEditField.Value;
            AnalyseStepCalculation(app.FileName,FrameName);
        end

        % Button pushed function: ZoomForceButton
        function ZoomForceButtonPushed(app, event)
            FrameName=app.FrameEditField.Value;
             AnalyseZoomForce(app.FileName,FrameName);
        end

        % Button pushed function: PlotForceDirectionButton
        function PlotForceDirectionButtonPushed(app, event)
            FrameName=app.FrameEditField.Value;
            App=AnalysePlotForceDirection();
            App.DataStart(app.FileName,FrameName);
        end

        % Button pushed function: SaveResultintextFileButton
        function SaveResultintextFileButtonPushed(app, event)
            FrameName=app.FrameEditField.Value;
            SaveResultInText(app.FileName,FrameName);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'FileName';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.FileNameEditField.Editable = 'off';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            app.FrameListBox = uilistbox(app.UIFigure);
            app.FrameListBox.ValueChangedFcn = createCallbackFcn(app, @FrameListBoxValueChanged, true);
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = '';
            app.FrameEditField = uieditfield(app.UIFigure, 'text');
            app.FrameEditField.Editable = 'off';
            app.ForceAnalysisButton = uibutton(app.UIFigure, 'push');
            app.ForceAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @ForceAnalysisButtonPushed, true);
            app.ForceAnalysisButton.Text = 'Force Analysis';
            app.AverageForceButton = uibutton(app.UIFigure, 'push');
            app.AverageForceButton.ButtonPushedFcn = createCallbackFcn(app, @AverageForceButtonPushed, true);
            app.AverageForceButton.Text = 'Average Force ';
            app.TrajectoryButton = uibutton(app.UIFigure, 'push');
            app.TrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @TrajectoryButtonPushed, true);
            app.TrajectoryButton.Text = 'Trajectory';
            app.CalculateStepButton = uibutton(app.UIFigure, 'push');
            app.CalculateStepButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateStepButtonPushed, true);
            app.CalculateStepButton.Text = 'Calculate Step';
            app.PlotForceDirectionButton = uibutton(app.UIFigure, 'push');
            app.PlotForceDirectionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotForceDirectionButtonPushed, true);
            app.PlotForceDirectionButton.Text = 'Plot Force Direction';
            app.ZoomForceButton = uibutton(app.UIFigure, 'push');
            app.ZoomForceButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomForceButtonPushed, true);
            app.ZoomForceButton.Text = 'Zoom Force';
            app.SaveResultintextFileButton = uibutton(app.UIFigure, 'push');
            app.SaveResultintextFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveResultintextFileButtonPushed, true);
            app.SaveResultintextFileButton.Text = 'Save Result in text File';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InterfaceForceAnalysisApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end